classdef VentanasInteres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EPILECTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        GrficayventanasdeintersdelasealLabel  matlab.ui.control.Label
        deVentanaLabel                 matlab.ui.control.Label
        Label_Test                     matlab.ui.control.Label
        VentanaEditFieldLabel          matlab.ui.control.Label
        VentanaEditField               matlab.ui.control.NumericEditField
        Boton_Anterior                 matlab.ui.control.Button
        Boton_Siguiente                matlab.ui.control.Button
        VentanaanteriorLabel           matlab.ui.control.Label
        VentanasiguienteLabel          matlab.ui.control.Label
        AnotacionesLabel               matlab.ui.control.Label
        TextoAnotacion                 matlab.ui.control.Label
        EEGPlot                        matlab.ui.control.UIAxes
        HoradeiniciodelregistroEEGHHMMSSEditFieldLabel  matlab.ui.control.Label
        HoradeiniciodelregistroEEGHHMMSSEditField  matlab.ui.control.EditField
        TestLabel                      matlab.ui.control.Label
        TopLabel                       matlab.ui.control.Label
        BottomLabel                    matlab.ui.control.Label
        SupLabes                       matlab.ui.control.Label
        BotumLabes                     matlab.ui.control.Label
        CanalavisualizarDropDownLabel  matlab.ui.control.Label
        CanalavisualizarDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        labels
        signal
        no_window
        window_samples
        fs_plot
        vector_ict
        vector_sano 
        no_window_road = 1;
        sweepict = 1;
        sweepsano = 1;
        %App que abre esta app
        CallingApp
        
        %Limites inferior y superior para graficar el intervalo
        bottom_lim
        top_lim  
        no_ventana
        canal_a_desplegar
        %Eje x del plot
        %time_series
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        Vector_Car_EEGVI
        EEG_VI
        Intervalo_Ventana_s
        Fs
        
        %Canales analizados
        ch_int
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Se obtienen las propiedades de la ventana anterior
            app.CallingApp = mainapp;    
            app.CanalavisualizarDropDown.Items = app.CallingApp.ch_int;
            app.CanalavisualizarDropDown.Value = app.CallingApp.ch_int{1};
            %Muestras inciales y finales de la ventana de
            %datos
            app.no_ventana = str2double(app.CanalavisualizarDropDown.Value);
            app.TestLabel.Text = num2str(app.no_ventana);
            
            app.bottom_lim = 1;
            app.top_lim = app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s;
            app.canal_a_desplegar = 1;
            record_interes = app.CallingApp.EEG_VI(app.canal_a_desplegar,...
                                app.bottom_lim:app.top_lim);
            size(record_interes)
            time_series = linspace(0,1,length(record_interes));
            %Eje x en el tiempo
            %t1 = hours(0):seconds(60):hours(seconds(app.CallingApp.Intervalo_Ventana_s));
            %app.CallingApp.Intervalo_Ventana_s
            tick1 = hours(0);
            tick2 = seconds(app.CallingApp.Intervalo_Ventana_s/2);
            tick3 = seconds(app.CallingApp.Intervalo_Ventana_s);
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot.XTick = xticks_array;  
            app.EEGPlot.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            if (app.CallingApp.Vector_Car_EEGVI(1)==1)
               app.TextoAnotacion.Text = "Ventana ICTAL"; 
               plot(app.EEGPlot,time_series,record_interes,'r');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==2)
                app.TextoAnotacion.Text = "Ventana sana"; 
                plot(app.EEGPlot,time_series,record_interes,'b');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==3)
                app.TextoAnotacion.Text = "Ventana PREICTAL"; 
                plot(app.EEGPlot,time_series,record_interes,'g');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==4)
                app.TextoAnotacion.Text = "Ventana interictal"; 
                plot(app.EEGPlot,time_series,record_interes,'k');
            end            
        end

        % Button pushed function: Boton_Siguiente
        function Boton_SiguienteButtonPushed(app, event)
            %Cálculo del # de ventana adelante
            app.no_ventana = app.no_ventana+1;
            if(app.no_ventana > length(app.CallingApp.Vector_Car_EEGVI))
                app.no_ventana = 1;
                app.bottom_lim = 1;
                app.top_lim = app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s*app.no_ventana;
            else
                app.bottom_lim = app.top_lim+1;
                app.top_lim = app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s*app.no_ventana;
            end
            app.TestLabel.Text = num2str(app.no_ventana);
            app.SupLabes.Text = num2str(app.top_lim);
            app.BotumLabes.Text = num2str(app.bottom_lim);
            %Gráfica de la nueva ventana            
            record_interes = app.CallingApp.EEG_VI(app.canal_a_desplegar,...
                                app.bottom_lim:app.top_lim);
            datasize = size(record_interes);
            app.TextoAnotacion.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
            
            time_series = linspace(0,1,length(record_interes));
            tick1 = hours(0);
            tick2 = seconds(app.CallingApp.Intervalo_Ventana_s/2);
            tick3 = seconds(app.CallingApp.Intervalo_Ventana_s);
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot.XTick = xticks_array;  
            app.EEGPlot.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            if (app.CallingApp.Vector_Car_EEGVI(1)==1)
               app.TextoAnotacion.Text = "Ventana ICTAL"; 
               plot(app.EEGPlot,time_series,record_interes,'r');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==2)
                app.TextoAnotacion.Text = "Ventana sana"; 
                plot(app.EEGPlot,time_series,record_interes,'b');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==3)
                app.TextoAnotacion.Text = "Ventana PREICTAL"; 
                plot(app.EEGPlot,time_series,record_interes,'g');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==4)
                app.TextoAnotacion.Text = "Ventana interictal"; 
                plot(app.EEGPlot,time_series,record_interes,'k');
            end    
        end

        % Button pushed function: Boton_Anterior
        function Boton_AnteriorButtonPushed(app, event)
            %Cálculo del # de ventana atrás
            app.no_ventana = app.no_ventana-1;
            if(app.no_ventana ==0)
                app.no_ventana = length(app.CallingApp.Vector_Car_EEGVI);
                app.top_lim = app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s*app.no_ventana;
                app.bottom_lim = app.top_lim-(app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s)+1;
            else
                app.top_lim = app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s*app.no_ventana;
                app.bottom_lim = app.top_lim-(app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s)+1;
            end
            app.TestLabel.Text = num2str(app.no_ventana);
            app.TestLabel.Text = num2str(app.no_ventana);
            app.SupLabes.Text = num2str(app.top_lim);
            app.BotumLabes.Text = num2str(app.bottom_lim);
            
            %Gráfica de la nueva ventana            
            record_interes = app.CallingApp.EEG_VI(app.canal_a_desplegar,...
                                app.bottom_lim:app.top_lim);
            datasize = size(record_interes);
            app.TextoAnotacion.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
            
            time_series = linspace(0,1,length(record_interes));
            tick1 = hours(0);
            tick2 = seconds(app.CallingApp.Intervalo_Ventana_s/2);
            tick3 = seconds(app.CallingApp.Intervalo_Ventana_s);
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot.XTick = xticks_array;  
            app.EEGPlot.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            if (app.CallingApp.Vector_Car_EEGVI(1)==1)
               app.TextoAnotacion.Text = "Ventana ICTAL"; 
               plot(app.EEGPlot,time_series,record_interes,'r');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==2)
                app.TextoAnotacion.Text = "Ventana sana"; 
                plot(app.EEGPlot,time_series,record_interes,'b');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==3)
                app.TextoAnotacion.Text = "Ventana PREICTAL"; 
                plot(app.EEGPlot,time_series,record_interes,'g');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==4)
                app.TextoAnotacion.Text = "Ventana interictal"; 
                plot(app.EEGPlot,time_series,record_interes,'k');
            end    
        end

        % Value changed function: VentanaEditField
        function VentanaEditFieldValueChanged(app, event)
            value = app.VentanaEditField.Value;
        end

        % Value changed function: 
        % HoradeiniciodelregistroEEGHHMMSSEditField
        function HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged(app, event)
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1);
            mm = hhmmss(2);
            ss = hhmmss(3);
            tick1 = hours(0)+hours(hh)+minutes(mm)+seconds(ss);
            tick2 = seconds(app.CallingApp.Intervalo_Ventana_s/2)+hours(hh)+minutes(mm)+seconds(ss);
            tick3 = seconds(app.CallingApp.Intervalo_Ventana_s)+hours(hh)+minutes(mm)+seconds(ss);
            record_interes = app.CallingApp.EEG_VI(1,1:app.CallingApp.Fs*app.CallingApp.Intervalo_Ventana_s);
            time_series = linspace(0,1,length(record_interes));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot.XTick = xticks_array;  
            app.EEGPlot.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
        end

        % Value changed function: CanalavisualizarDropDown
        function CanalavisualizarDropDownValueChanged(app, event)
            app.canal_a_desplegar = str2double(app.CanalavisualizarDropDown.Value);
             %Gráfica de la nueva ventana            
            record_interes = app.CallingApp.EEG_VI(app.canal_a_desplegar,...
                                app.bottom_lim:app.top_lim);
            datasize = size(record_interes);
            app.TextoAnotacion.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
            
            time_series = linspace(0,1,length(record_interes));
            tick1 = hours(0);
            tick2 = seconds(app.CallingApp.Intervalo_Ventana_s/2);
            tick3 = seconds(app.CallingApp.Intervalo_Ventana_s);
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot.XTick = xticks_array;  
            app.EEGPlot.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            if (app.CallingApp.Vector_Car_EEGVI(1)==1)
               app.TextoAnotacion.Text = "Ventana ICTAL"; 
               plot(app.EEGPlot,time_series,record_interes,'r');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==2)
                app.TextoAnotacion.Text = "Ventana sana"; 
                plot(app.EEGPlot,time_series,record_interes,'b');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==3)
                app.TextoAnotacion.Text = "Ventana PREICTAL"; 
                plot(app.EEGPlot,time_series,record_interes,'g');
            elseif (app.CallingApp.Vector_Car_EEGVI(1)==4)
                app.TextoAnotacion.Text = "Ventana interictal"; 
                plot(app.EEGPlot,time_series,record_interes,'k');
            end                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EPILECTICEEGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.EPILECTICEEGANALYSISTOOLBOXLabel.Text = 'EPILECTIC EEG ANALYSIS TOOLBOX';
            app.GrficayventanasdeintersdelasealLabel = uilabel(app.UIFigure);
            app.GrficayventanasdeintersdelasealLabel.Text = 'Gráfica y ventanas de interés de la señal';
            app.deVentanaLabel = uilabel(app.UIFigure);
            app.deVentanaLabel.Text = '/';
            app.Label_Test = uilabel(app.UIFigure);
            app.Label_Test.Text = 'de';
            app.VentanaEditFieldLabel = uilabel(app.UIFigure);
            app.VentanaEditFieldLabel.Text = 'Ventana';
            app.VentanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VentanaEditField.ValueChangedFcn = createCallbackFcn(app, @VentanaEditFieldValueChanged, true);
            app.Boton_Anterior = uibutton(app.UIFigure, 'push');
            app.Boton_Anterior.ButtonPushedFcn = createCallbackFcn(app, @Boton_AnteriorButtonPushed, true);
            app.Boton_Anterior.Icon = 'Flecha_izquierda.jpg';
            app.Boton_Anterior.Text = '';
            app.Boton_Siguiente = uibutton(app.UIFigure, 'push');
            app.Boton_Siguiente.ButtonPushedFcn = createCallbackFcn(app, @Boton_SiguienteButtonPushed, true);
            app.Boton_Siguiente.Icon = 'Flecha_derecha.jpg';
            app.Boton_Siguiente.Text = '';
            app.VentanaanteriorLabel = uilabel(app.UIFigure);
            app.VentanaanteriorLabel.Text = 'Ventana anterior';
            app.VentanasiguienteLabel = uilabel(app.UIFigure);
            app.VentanasiguienteLabel.Text = 'Ventana siguiente';
            app.AnotacionesLabel = uilabel(app.UIFigure);
            app.AnotacionesLabel.Text = 'Anotaciones:';
            app.TextoAnotacion = uilabel(app.UIFigure);
            app.EEGPlot = uiaxes(app.UIFigure);
            title(app.EEGPlot, 'Señal EEG')
            xlabel(app.EEGPlot, 'Tiempo (HH:MM:SS)')
            ylabel(app.EEGPlot, 'Voltaje (uV)')
            app.EEGPlot.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EEGPlot.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel = uilabel(app.UIFigure);
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel.Text = {'Hora de inicio del registro EEG'; ' HH:MM:SS'};
            app.HoradeiniciodelregistroEEGHHMMSSEditField = uieditfield(app.UIFigure, 'text');
            app.HoradeiniciodelregistroEEGHHMMSSEditField.ValueChangedFcn = createCallbackFcn(app, @HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged, true);
            app.HoradeiniciodelregistroEEGHHMMSSEditField.Value = '00:00:00';
            app.TestLabel = uilabel(app.UIFigure);
            app.TestLabel.Text = '-';
            app.TopLabel = uilabel(app.UIFigure);
            app.TopLabel.Text = 'Top:';
            app.BottomLabel = uilabel(app.UIFigure);
            app.BottomLabel.Text = 'Bottom:';
            app.SupLabes = uilabel(app.UIFigure);
            app.SupLabes.Text = '-';
            app.BotumLabes = uilabel(app.UIFigure);
            app.BotumLabes.Text = '-';
            app.CanalavisualizarDropDownLabel = uilabel(app.UIFigure);
            app.CanalavisualizarDropDownLabel.Text = 'Canal a visualizar';
            app.CanalavisualizarDropDown = uidropdown(app.UIFigure);
            app.CanalavisualizarDropDown.Items = {'-'};
            app.CanalavisualizarDropDown.ValueChangedFcn = createCallbackFcn(app, @CanalavisualizarDropDownValueChanged, true);
            app.CanalavisualizarDropDown.Value = '-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanasInteres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end