classdef clasificadorventanainteres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CargarMATButton                matlab.ui.control.Button
        CargarEDFButton                matlab.ui.control.Button
        CargarClasificadorButton       matlab.ui.control.Button
        CargadelasealEEGLabel          matlab.ui.control.Label
        EPILECTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        HzIctal                        matlab.ui.control.Label
        TextoDimensionesIctal          matlab.ui.control.Label
        TextoTiempoIctal               matlab.ui.control.Label
        FreqMuestreoFsEditFieldLabel   matlab.ui.control.Label
        FreqMuestreoFsIctalEditField   matlab.ui.control.NumericEditField
        ProcesarlasealEEGButton        matlab.ui.control.Button
        sLabel                         matlab.ui.control.Label
        TiempodeventanaEditFieldLabel  matlab.ui.control.Label
        TiempodeventanaEditField       matlab.ui.control.NumericEditField
        ObservaranotacionesButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        %
        EEG_a_analizar
        Header_EEG
        Fs        
        
        %Caracteristicas
        cEEG
        muestras_eeg
        
        %Estructura con los resultados de un clasificador
        Struct_Clasificador
        
        %Aplicación de Ventanas de interes
        InterestWindows
        
        %Vector de caracteristicas para la app de ventanas de interes
        Vector_Car_EEGVI
        EEG_VI
        
        Intervalo_Ventana_s
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarEDFButton
        function CargarEDFButtonPushed(app, event)
            %Abrir archivo EDF ICTAL
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.EEG_a_analizar = record;
                app.Header_EEG = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = hdr.frequency(1);
                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                
%                 %Se hace el cálculo para dejar que la persona seleccione 1 de
%                 %todos los canales que tiene
                  datasize = size(app.EEG_a_analizar);
%                 numero_canalesIctal = min(datasize);
%                 DropDownOptionsIctal  = num2cell(zeros(1,numero_canalesIctal));
%                 for i=1:numero_canalesIctal
%                     DropDownOptionsIctal{i} = num2str(i);
%                 end
%                 app.CanaldelEEGIctalaanalizarDropDown.Items = DropDownOptionsIctal;
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesIctal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoIctal.Text = append("Duración: ",num2str(hdr.records),"s");
            end
        end

        % Button pushed function: CargarMATButton
        function CargarMATButtonPushed(app, event)
            %Seleccionar archivo MAT ICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.EEG_a_analizar = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                
%                 %Se hace el cálculo para dejar que la persona seleccione 1 de
%                 %todos los canales que tiene
                  datasize = size(app.EEG_a_analizar);
%                 numero_canalesIctal = min(datasize);
%                 DropDownOptionsIctal  = num2cell(zeros(1,numero_canalesIctal));
%                 for i=1:numero_canalesIctal
%                     DropDownOptionsIctal{i} = num2str(i);
%                 end                
%                 app.CanaldelEEGIctalaanalizarDropDown.Items = DropDownOptionsIctal;
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesIctal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoIctal.Text = append("Duración: ",num2str(workspace.eeg_struct.data_length_sec),"s");
            end     
        end

        % Button pushed function: CargarClasificadorButton
        function CargarClasificadorButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            espacio_trabajo_temp = load(strcat(path,file),'Clasificador_Entrenado');
            app.Struct_Clasificador = espacio_trabajo_temp.Clasificador_Entrenado;
        end

        % Button pushed function: ProcesarlasealEEGButton
        function ProcesarlasealEEGButtonPushed(app, event)
            app.EEG_a_analizar;
            app.Fs;
            %Extracción de Características
            s_ventana = app.TiempodeventanaEditField.Value;
            app.Intervalo_Ventana_s = s_ventana;
            app.cEEG = 1;%Sólo el canal 1 por el momento
            canales = 1; %Sólo 1 canal por el momento
            app.muestras_eeg =round(s_ventana*app.Fs);
            if((s_ventana*app.Fs-app.muestras_eeg)<0)
                app.muestras_eeg = app.muestras_eeg-1;
            end
            %Struct_Clasificador
            Vector_Opciones = app.Struct_Clasificador.Vector_op;
            AlgoritmoAA = app.Struct_Clasificador.Clasificador;
            if(app.Struct_Clasificador.Caracteristicas.Tipo_Caracteristicas == 0)
                [VectorCaracteristicas,Ventanadatos,~]=FeaturesV2(app.EEG_a_analizar,app.Fs,canales,app.muestras_eeg,app.cEEG,Vector_Opciones);
            else
                WaveletMadre = app.Struct_Clasificador.Caracteristicas.Madre;
                Niveles_des = app.Struct_Clasificador.Caracteristicas.Descomp;
                [VectorCaracteristicas,~,coeffEEG,~] = FeaturesV2wavelet(app.EEG_a_analizar,app.muestras_eeg,app.cEEG,WaveletMadre,Niveles_des,Vector_Opciones);
            end
            
            %Clasificación con el clasificador realizado
            if(app.Struct_Clasificador.Tipo_Clasificador == 0)
                %RNN
                VectorCaracteristicas = VectorCaracteristicas';
                Etiquetas_Generadas_EEGONEHOT = AlgoritmoAA(VectorCaracteristicas);
                %Se pasan las etiquetas de One-Hot a un vector con números
                %1, 2, 3 o 4, dependiendo de la cantidad de clases
                nwmax = length(VectorCaracteristicas);
                Etiquetas_Generadas_EEG = zeros(1,nwmax);
                for i=1:nwmax
                    ventana_interes = Etiquetas_Generadas_EEGONEHOT(:,i);
                    indice_max = find(ventana_interes==max(ventana_interes));
                    if 1 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 1;
                    elseif 2 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 2;
                    elseif 3 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 3;
                    elseif 4 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 4;
                    end
                end
               app.Vector_Car_EEGVI = Etiquetas_Generadas_EEG;
               app.EEG_VI = app.EEG_a_analizar;               
            elseif(app.Struct_Clasificador.Tipo_Clasificador == 1)
                %SVM
                VectorCaracteristicas = VectorCaracteristicas';
                Etiquetas_Generadas_EEG= predict(AlgoritmoAA, VectorCaracteristicas);                
            end        
            app.ObservaranotacionesButton.Enable = 'on';
        end

        % Button pushed function: ObservaranotacionesButton
        function ObservaranotacionesButtonPushed(app, event)
                app.InterestWindows = VentanasInteres(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CargarMATButton = uibutton(app.UIFigure, 'push');
            app.CargarMATButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATButtonPushed, true);
            app.CargarMATButton.Text = 'Cargar MAT';
            app.CargarEDFButton = uibutton(app.UIFigure, 'push');
            app.CargarEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFButtonPushed, true);
            app.CargarEDFButton.Text = 'Cargar EDF';
            app.CargarClasificadorButton = uibutton(app.UIFigure, 'push');
            app.CargarClasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @CargarClasificadorButtonPushed, true);
            app.CargarClasificadorButton.Text = 'Cargar Clasificador';
            app.CargadelasealEEGLabel = uilabel(app.UIFigure);
            app.CargadelasealEEGLabel.Text = 'Carga de la señal EEG';
            app.EPILECTICEEGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.EPILECTICEEGANALYSISTOOLBOXLabel.Text = 'EPILECTIC EEG ANALYSIS TOOLBOX';
            app.HzIctal = uilabel(app.UIFigure);
            app.HzIctal.Text = 'Hz';
            app.TextoDimensionesIctal = uilabel(app.UIFigure);
            app.TextoDimensionesIctal.Text = 'Tamaño: - x -';
            app.TextoTiempoIctal = uilabel(app.UIFigure);
            app.TextoTiempoIctal.Text = 'Duración: -';
            app.FreqMuestreoFsEditFieldLabel = uilabel(app.UIFigure);
            app.FreqMuestreoFsEditFieldLabel.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsIctalEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcesarlasealEEGButton = uibutton(app.UIFigure, 'push');
            app.ProcesarlasealEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarlasealEEGButtonPushed, true);
            app.ProcesarlasealEEGButton.Text = 'Procesar la señal EEG';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.TiempodeventanaEditFieldLabel = uilabel(app.UIFigure);
            app.TiempodeventanaEditFieldLabel.Text = 'Tiempo de ventana';
            app.TiempodeventanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempodeventanaEditField.Value = 1;
            app.ObservaranotacionesButton = uibutton(app.UIFigure, 'push');
            app.ObservaranotacionesButton.ButtonPushedFcn = createCallbackFcn(app, @ObservaranotacionesButtonPushed, true);
            app.ObservaranotacionesButton.Enable = 'off';
            app.ObservaranotacionesButton.Text = 'Observar anotaciones';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clasificadorventanainteres

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end