classdef loginanotaciones < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Image                        matlab.ui.control.Image
        IniciarSesinButton           matlab.ui.control.StateButton
        UsuarioEditFieldLabel        matlab.ui.control.Label
        UsuarioEditField             matlab.ui.control.EditField
        ContraseaEditFieldLabel      matlab.ui.control.Label
        ContraseaEditField           matlab.ui.control.EditField
        CrearalgoritmoButton         matlab.ui.control.StateButton
        AnotacionesAutomticasButton  matlab.ui.control.Button
        EPILECTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        ANOTACIONESAUTOMTICASLabel   matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        usuario = 0
        pass = 0
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        MLTesteo
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.Label.Text = num2str(app.CallingApp.MLTesteo);
            app.AnotacionesAutomticasButton.Enable = 'off';
            app.CrearalgoritmoButton.Enable = 'off';
        end

        % Value changed function: IniciarSesinButton
        function IniciarSesinButtonValueChanged(app, event)
      if ((app.UsuarioEditField.Value == "DavidVela")&&(app.ContraseaEditField.Value == "1234"))
          app.usuario = 1;
          app.AnotacionesAutomticasButton.Enable = 'on';
          app.CrearalgoritmoButton.Enable = 'off';
      end  
      if ((app.UsuarioEditField.Value == "Doctor1")&&(app.ContraseaEditField.Value == "5678"))
          app.usuario = 1;
          app.AnotacionesAutomticasButton.Enable = 'on';
          app.CrearalgoritmoButton.Enable = 'on';
      end  
        end

        % Value changed function: CrearalgoritmoButton
        function CrearalgoritmoButtonValueChanged(app, event)
            value = app.CrearalgoritmoButton.Value;
            
        end

        % Button pushed function: AnotacionesAutomticasButton
        function AnotacionesAutomticasButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logouvg.jpg';
            app.IniciarSesinButton = uibutton(app.UIFigure, 'state');
            app.IniciarSesinButton.ValueChangedFcn = createCallbackFcn(app, @IniciarSesinButtonValueChanged, true);
            app.IniciarSesinButton.Text = 'Iniciar Sesión';
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.CrearalgoritmoButton = uibutton(app.UIFigure, 'state');
            app.CrearalgoritmoButton.ValueChangedFcn = createCallbackFcn(app, @CrearalgoritmoButtonValueChanged, true);
            app.CrearalgoritmoButton.Text = 'Crear algoritmo';
            app.AnotacionesAutomticasButton = uibutton(app.UIFigure, 'push');
            app.AnotacionesAutomticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesAutomticasButtonPushed, true);
            app.AnotacionesAutomticasButton.Text = 'Anotaciones Automáticas';
            app.EPILECTICEEGANALYSISTOOLBOXLabel = uilabel(app.UIFigure);
            app.EPILECTICEEGANALYSISTOOLBOXLabel.Text = 'EPILECTIC EEG ANALYSIS TOOLBOX';
            app.ANOTACIONESAUTOMTICASLabel = uilabel(app.UIFigure);
            app.ANOTACIONESAUTOMTICASLabel.Text = 'ANOTACIONES AUTOMÁTICAS';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loginanotaciones(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end