classdef consultas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConsultasUIFigure               matlab.ui.Figure
        DescargarpruebasPanel           matlab.ui.container.Panel
        SeleccionarnmeroEditField       matlab.ui.control.EditField
        SeleccionarnmeroEditFieldLabel  matlab.ui.control.Label
        TodoslosarchivosCheckBox        matlab.ui.control.CheckBox
        DescargarButton                 matlab.ui.control.Button
        SeleccionarrangoEditField       matlab.ui.control.EditField
        SeleccionarrangoEditFieldLabel  matlab.ui.control.Label
        ActualizarButton                matlab.ui.control.Button
        RegresarButton                  matlab.ui.control.Button
        NuevaconsultaButton             matlab.ui.control.Button
        LeerButton                      matlab.ui.control.Button
        AgregarnuevoarchivoPanel        matlab.ui.container.Panel
        NoCanalesEditField              matlab.ui.control.EditField
        NoCanalesEditFieldLabel         matlab.ui.control.Label
        FrecuenciaHzEditField           matlab.ui.control.EditField
        FrecuenciaHzEditFieldLabel      matlab.ui.control.Label
        FechadelexamenEditField         matlab.ui.control.EditField
        FechadelexamenEditFieldLabel    matlab.ui.control.Label
        GuardarButton                   matlab.ui.control.Button
        SeleccionarButton               matlab.ui.control.Button
        DuracinsEditField               matlab.ui.control.EditField
        DuracinsEditFieldLabel          matlab.ui.control.Label
        HoradeinicioEditField           matlab.ui.control.EditField
        HoradeinicioEditFieldLabel      matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        CondicinEditField               matlab.ui.control.EditField
        CondicinEditFieldLabel          matlab.ui.control.Label
        DiagnsticoTextArea              matlab.ui.control.TextArea
        DiagnsticoTextAreaLabel         matlab.ui.control.Label
        AntecedentesTextArea            matlab.ui.control.TextArea
        AntecedentesTextAreaLabel       matlab.ui.control.Label
        preescripcinLabel               matlab.ui.control.Label
        deLabel                         matlab.ui.control.Label
        patolgicosLabel                 matlab.ui.control.Label
        personalesLabel                 matlab.ui.control.Label
        FechadeNacimientoEditField      matlab.ui.control.EditField
        FechadeNacimientoEditFieldLabel  matlab.ui.control.Label
        SexoEditField                   matlab.ui.control.EditField
        SexoEditFieldLabel              matlab.ui.control.Label
        PacienteEditField               matlab.ui.control.EditField
        PacienteEditFieldLabel          matlab.ui.control.Label
        ConsultadepacientesypruebasLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        datos
        Conexion
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            app.CallingApp = mainapp;
            global p
            p.MyData=[];
            app.Conexion = con;
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Close request function: ConsultasUIFigure
        function ConsultasUIFigureCloseRequest(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: LeerButton
        function LeerButtonPushed(app, event)
            global p
            id_paciente = string(app.PacienteEditField.Value);
            %------ Conexion con la base de datos------------------------
            conn = app.Conexion;
            query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"'];
            
            query2 = ['SELECT * ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query); %Revisar
            data2 = fetch(conn,query2);
            
            if(isempty(data)==1)
                errordlg('Paciente no encontrado','Error');
                ini = char(' ');
                p.MyData=[];
                app.PacienteEditField.Value = ini;
                app.SexoEditField.Value = ini;
                app.FechadeNacimientoEditField.Value = ini;
                app.CondicinEditField.Value = ini;
                app.AntecedentesTextArea.Value = ini;
                app.DiagnsticoTextArea.Value = ini;
                app.UITable.Data = p.MyData;
                
            else
                app.SexoEditField.Value = sprintf('%s',cell2mat(data.sexo));
                app.FechadeNacimientoEditField.Value = sprintf('%s',cell2mat(data.fecha_nacimiento));
                cond = sprintf('%s',cell2mat(data.condicion));
                if strcmp(cond,'SANO')
                    app.CondicinEditField.Value = 'No padece epilepsia';
                else
                    app.CondicinEditField.Value = 'Padece epilepsia';
                end
                app.AntecedentesTextArea.Value = sprintf('%s',cell2mat(data.antecedentes));
                app.DiagnsticoTextArea.Value = sprintf('%s',cell2mat(data.diagnostico));
                % Mostrar datos en tablas
                dimension=size(data2);
                for i=1:dimension(1)
                    prueba = sprintf('%d',data2.id_prueba(i));
                    fecha = sprintf('%s',cell2mat(data2.fecha_prueba(i)));
                    hora = sprintf('%s',cell2mat(data2.hora_inicio(i)));
                    duracion = sprintf('%d',data2.duracion(i));
                    frecuencia = sprintf('%d',data2.frecuencia(i));
                    canales = sprintf('%d',data2.no_canales(i));
                    p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {frecuencia} {canales}]];
                end
                app.UITable.Data = p.MyData;
            end
            
            %Clear variables
            clear query query2
        end

        % Button pushed function: NuevaconsultaButton
        function NuevaconsultaButtonPushed(app, event)
            global p    
            ini = char(' ');
            p.MyData=[];
            app.PacienteEditField.Value = ini;
            app.SexoEditField.Value = ini;
            app.FechadeNacimientoEditField.Value = ini;
            app.CondicinEditField.Value = ini;
            app.AntecedentesTextArea.Value = ini;
            app.DiagnsticoTextArea.Value = ini;
            app.UITable.Data = p.MyData;
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;
            app.SeleccionarnmeroEditField.Value = ini;
            app.SeleccionarrangoEditField.Value = ini;
            
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            global p
            p.MyData=[];
            ini = char(' ');
            app.HoradeinicioEditField.Value = ini;
            app.DuracinsEditField .Value = ini;
            app.FechadelexamenEditField.Value = ini;
            app.FrecuenciaHzEditField.Value = ini;
            app.NoCanalesEditField.Value = ini;
            app.UITable.Data = p.MyData;
            id_paciente=app.PacienteEditField.Value;
            conn = app.Conexion;
            
            %Set query to execute on the database
            query = ['SELECT * ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                prueba = sprintf('%d',data.id_prueba(i));
                fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                duracion = sprintf('%d',data.duracion(i));
                freq = sprintf('%d',data.frecuencia(i));
                canales = sprintf('%d',data.no_canales(i));
                p.MyData=[p.MyData; [{i} {prueba} {fecha} {hora} {duracion} {freq} {canales}]];
            end
            app.UITable.Data = p.MyData;
            
            %Clear variables
            clear query
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
          
            [file,path,indx] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
            [hdr, record] = edfread(strcat(path,file));
            tinicio = sprintf('%s',hdr.starttime);
            finicio = sprintf('%s',hdr.startdate);
            duracions = sprintf('%d',hdr.records);
            frecuencia = sprintf('%d',hdr.frequency(1));
            canales = sprintf('%d',hdr.ns);
            app.HoradeinicioEditField.Value = tinicio;
            app.DuracinsEditField.Value = duracions;
            app.FechadelexamenEditField.Value = finicio;
            app.FrecuenciaHzEditField.Value = frecuencia;
            app.NoCanalesEditField.Value = canales;
            serializedData = serialize(record);
            
            app.datos=record;
            
            % Guardar los vectores del archivo original en archivo.bin
            fid = fopen('archivo.bin', 'w');
            fwrite(fid, serializedData, '*uint8');
            fclose(fid);
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            id_paciente = str2num(app.PacienteEditField.Value);
            tinicio = app.HoradeinicioEditField.Value;
            duracions = str2double(app.DuracinsEditField.Value);
            finicio = app.FechadelexamenEditField.Value;
            frecuencia = str2double(app.FrecuenciaHzEditField.Value);
            canales = str2double(app.NoCanalesEditField.Value);
        
            % Leer archivo.bin para insertar como binario en la BD
            fid = fopen('archivo.bin', 'r');
            binaryData = fread(fid, '*uint8');
            fclose(fid);
            
            conn = app.Conexion;
            columnas = {'id_paciente', 'fecha_prueba', 'hora_inicio', 'duracion', 'frecuencia', 'no_canales', 'prueba'};
            nuevo = {id_paciente, finicio, tinicio, duracions, frecuencia, canales, binaryData};
            insert(conn, 'pruebas', columnas, nuevo);
            
            % Insertar datos en tabla de pruebas_datos
            record = app.datos;
            for i = 1:canales
                
            end
            
            msgbox('Se ha guardado correctamente','Mensaje');
            
            %Clear variables
            clear query
        end

        % Value changed function: TodoslosarchivosCheckBox
        function TodoslosarchivosCheckBoxValueChanged(app, event)
            value = app.TodoslosarchivosCheckBox.Value;
            if value==1
                app.SeleccionarnmeroEditField.Enable='off';
                app.SeleccionarrangoEditField.Enable='off';
            else 
                app.SeleccionarnmeroEditField.Enable='on';
                app.SeleccionarrangoEditField.Enable='on';
            end
        end

        % Value changed function: SeleccionarrangoEditField
        function SeleccionarrangoEditFieldValueChanged(app, event)
            value = app.SeleccionarrangoEditField.Value;
            if isempty(value)
                app.TodoslosarchivosCheckBox.Enable='on';
                app.SeleccionarnmeroEditField.Enable='on';
            else
                app.TodoslosarchivosCheckBox.Enable='off';
                app.SeleccionarnmeroEditField.Enable='off';
            end
        end

        % Value changed function: SeleccionarnmeroEditField
        function SeleccionarnmeroEditFieldValueChanged(app, event)
            value = app.SeleccionarnmeroEditField.Value;
            if isempty(value)
                app.TodoslosarchivosCheckBox.Enable='on';
                app.SeleccionarrangoEditField.Enable='on';
            else
                app.TodoslosarchivosCheckBox.Enable='off';
                app.SeleccionarrangoEditField.Enable='off';
            end
        end

        % Button pushed function: DescargarButton
        function DescargarButtonPushed(app, event)
            id_paciente = app.PacienteEditField.Value;
            checkbx = app.TodoslosarchivosCheckBox.Value;
            rango = app.SeleccionarrangoEditField.Value;
            numero = app.SeleccionarnmeroEditField.Value;
            if isempty(id_paciente)
                errordlg('Ingrese número de paciente','Error');
            else
                conn = app.Conexion;
                
                %Set query to execute on the database
                query = ['SELECT * ' ...
                    'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                    id_paciente...
                    '"'];
                
                data = fetch(conn,query);
                
                if(chekbx==1)
                    dimension=size(data);
                    for i=1:dimension(1)
                        prueba = sprintf('%d',data.id_prueba(i));
                        fecha = sprintf('%s',cell2mat(data.fecha_prueba(i)));
                        hora = sprintf('%s',cell2mat(data.hora_inicio(i)));
                        duracion = sprintf('%d',data.duracion(i));
                        freq = sprintf('%d',data.frecuencia(i));
                        canales = sprintf('%d',data.no_canales(i));
                    end
                end
                
                %Clear variables
                clear query
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConsultasUIFigure = uifigure('Visible', 'off');
            app.ConsultasUIFigure.Name = 'Consultas';
            app.ConsultasUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConsultasUIFigureCloseRequest, true);
            app.ConsultadepacientesypruebasLabel = uilabel(app.ConsultasUIFigure);
            app.ConsultadepacientesypruebasLabel.Text = 'Consulta de pacientes y pruebas';
            app.PacienteEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.PacienteEditFieldLabel.Text = 'Paciente';
            app.PacienteEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.SexoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.SexoEditFieldLabel.Text = 'Sexo';
            app.SexoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.SexoEditField.Editable = 'off';
            app.FechadeNacimientoEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.FechadeNacimientoEditFieldLabel.Text = 'Fecha de Nacimiento';
            app.FechadeNacimientoEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.FechadeNacimientoEditField.Editable = 'off';
            app.personalesLabel = uilabel(app.ConsultasUIFigure);
            app.personalesLabel.Text = 'personales';
            app.patolgicosLabel = uilabel(app.ConsultasUIFigure);
            app.patolgicosLabel.Text = 'patológicos';
            app.deLabel = uilabel(app.ConsultasUIFigure);
            app.deLabel.Text = 'de';
            app.preescripcinLabel = uilabel(app.ConsultasUIFigure);
            app.preescripcinLabel.Text = 'preescripción';
            app.AntecedentesTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.AntecedentesTextAreaLabel.Text = 'Antecedentes';
            app.AntecedentesTextArea = uitextarea(app.ConsultasUIFigure);
            app.AntecedentesTextArea.Editable = 'off';
            app.DiagnsticoTextAreaLabel = uilabel(app.ConsultasUIFigure);
            app.DiagnsticoTextAreaLabel.Text = 'Diagnóstico';
            app.DiagnsticoTextArea = uitextarea(app.ConsultasUIFigure);
            app.DiagnsticoTextArea.Editable = 'off';
            app.CondicinEditFieldLabel = uilabel(app.ConsultasUIFigure);
            app.CondicinEditFieldLabel.Text = 'Condición';
            app.CondicinEditField = uieditfield(app.ConsultasUIFigure, 'text');
            app.CondicinEditField.Editable = 'off';
            app.UITable = uitable(app.ConsultasUIFigure);
            app.UITable.ColumnName = {'No.'; 'Prueba'; 'Fecha'; 'Hora'; 'Duración (s)'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.AgregarnuevoarchivoPanel = uipanel(app.ConsultasUIFigure);
            app.AgregarnuevoarchivoPanel.Title = 'Agregar nuevo archivo';
            app.HoradeinicioEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.HoradeinicioEditFieldLabel.Text = 'Hora de inicio';
            app.HoradeinicioEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.HoradeinicioEditField.Editable = 'off';
            app.DuracinsEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.DuracinsEditFieldLabel.Text = 'Duración (s)';
            app.DuracinsEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.DuracinsEditField.Editable = 'off';
            app.SeleccionarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.GuardarButton = uibutton(app.AgregarnuevoarchivoPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.FechadelexamenEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FechadelexamenEditFieldLabel.Text = 'Fecha del examen';
            app.FechadelexamenEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FechadelexamenEditField.Editable = 'off';
            app.FrecuenciaHzEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.FrecuenciaHzEditFieldLabel.Text = 'Frecuencia (Hz)';
            app.FrecuenciaHzEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.FrecuenciaHzEditField.Editable = 'off';
            app.NoCanalesEditFieldLabel = uilabel(app.AgregarnuevoarchivoPanel);
            app.NoCanalesEditFieldLabel.Text = 'No. Canales';
            app.NoCanalesEditField = uieditfield(app.AgregarnuevoarchivoPanel, 'text');
            app.NoCanalesEditField.Editable = 'off';
            app.LeerButton = uibutton(app.ConsultasUIFigure, 'push');
            app.LeerButton.ButtonPushedFcn = createCallbackFcn(app, @LeerButtonPushed, true);
            app.LeerButton.Text = 'Leer';
            app.NuevaconsultaButton = uibutton(app.ConsultasUIFigure, 'push');
            app.NuevaconsultaButton.ButtonPushedFcn = createCallbackFcn(app, @NuevaconsultaButtonPushed, true);
            app.NuevaconsultaButton.Text = 'Nueva consulta';
            app.RegresarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.ActualizarButton = uibutton(app.ConsultasUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.DescargarpruebasPanel = uipanel(app.ConsultasUIFigure);
            app.DescargarpruebasPanel.Title = 'Descargar pruebas';
            app.SeleccionarrangoEditFieldLabel = uilabel(app.DescargarpruebasPanel);
            app.SeleccionarrangoEditFieldLabel.Text = 'Seleccionar rango';
            app.SeleccionarrangoEditField = uieditfield(app.DescargarpruebasPanel, 'text');
            app.SeleccionarrangoEditField.ValueChangedFcn = createCallbackFcn(app, @SeleccionarrangoEditFieldValueChanged, true);
            app.DescargarButton = uibutton(app.DescargarpruebasPanel, 'push');
            app.DescargarButton.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.DescargarButton.Text = 'Descargar';
            app.TodoslosarchivosCheckBox = uicheckbox(app.DescargarpruebasPanel);
            app.TodoslosarchivosCheckBox.ValueChangedFcn = createCallbackFcn(app, @TodoslosarchivosCheckBoxValueChanged, true);
            app.TodoslosarchivosCheckBox.Text = 'Todos los archivos';
            app.SeleccionarnmeroEditFieldLabel = uilabel(app.DescargarpruebasPanel);
            app.SeleccionarnmeroEditFieldLabel.Text = 'Seleccionar número';
            app.SeleccionarnmeroEditField = uieditfield(app.DescargarpruebasPanel, 'text');
            app.SeleccionarnmeroEditField.ValueChangedFcn = createCallbackFcn(app, @SeleccionarnmeroEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.ConsultasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = consultas(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConsultasUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConsultasUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConsultasUIFigure)
        end
    end
end