classdef credenciales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cred                     matlab.ui.Figure
        DebugEditField           matlab.ui.control.EditField
        DebugEditFieldLabel      matlab.ui.control.Label
        IntroduzcasuscredencialesdeaccesoLabel  matlab.ui.control.Label
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField         matlab.ui.control.EditField
        UsuarioEditFieldLabel    matlab.ui.control.Label
        ConectarButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        pass
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)

                try
                    % configureODBCDataSource
                    con = database('toolbox', app.UsuarioEditField.Value, app.ContraseaEditField.Value);
                    query = ['SELECT * FROM humana.pacientes'];
                    data = fetch(con,query);
                    % disp('Conexión realizada correctamente');
                    msgbox('Conexión realizada correctamente.', 'Mensaje');
                    %app.Conexion = con;
                    clear query
                    delete(app)
                    % Open the options dialog and pass inputs
%                     app.CallingApp.DesconectarButton.Enable = 'on';
%                     app.AgregarnuevopacienteButton.Enable = 'on';
%                     app.ConsultarButton.Enable = 'on';
%                     app.AnalizarpruebaButton.Enable = 'on';
                catch exception
                    if (strcmp(exception.identifier, 'database:database:ODBCDriverError'))
                        % disp('Ha ocurrido u error durante la conexión a la base de datos: ODBCDriverError');
                        errordlg(['Ha ocurrido un error durante la conexión a la base de datos. ' ...
                            'Verifique que la base de datos "HUMANA" esté creada con sus respectivas tablas.'],'Error');
                        app.ConectarButton.Value=0;
                        return;
                    elseif (strcmp(exception.identifier, 'database:database:dataSourceNameNotFound'))
                        % disp('Ha ocurrido un error durante la conexión a la base de datos: dataSourceNameNotFound. Por favor configure una conexión ODBC de nombre toolbox');
                        errordlg(['Ha ocurrido un error durante la conexión a la base de datos. ' ...
                            'Configure una conexión ODBC con el nombre "toolbox".'],'Error');
                        app.ConectarButton.Value=0;
                        return;
                    else
                        errordlg(['Error de inicio de sesión. ' ...
                        'Revise sus credenciales.'],'Error');
                    end
                    
                    rethrow(exception);
                end
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.DebugEditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.DebugEditField.Value)
                    if strlength(changingValue) == 0
                        app.DebugEditField.Value = "";
                    else
                        app.DebugEditField.Value = app.DebugEditField.Value(1:strlength(changingValue));
                    end
                else
                    app.DebugEditField.Value = strcat(app.DebugEditField.Value, changingValue(end));
                end
            else
                app.DebugEditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ContraseaEditField.Value = temp;
            else
                app.ContraseaEditField.Value = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cred = uifigure('Visible', 'off');
            app.cred.Name = 'Iniciar sesión';
            app.ConectarButton = uibutton(app.cred, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.UsuarioEditFieldLabel = uilabel(app.cred);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.cred);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.IntroduzcasuscredencialesdeaccesoLabel = uilabel(app.cred);
            app.IntroduzcasuscredencialesdeaccesoLabel.Text = 'Introduzca sus credenciales de acceso.';
            app.DebugEditFieldLabel = uilabel(app.cred);
            app.DebugEditFieldLabel.Text = 'Debug';
            app.DebugEditField = uieditfield(app.cred, 'text');
            app.DebugEditField.Editable = 'off';
            % Show the figure after all components are created
            app.cred.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = credenciales(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cred)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cred)
        end
    end
end